classdef finalfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Topic1Tab                       matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        FilterAudioFileButton           matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        playfilteredButton              matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        Topic2Tab                       matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        SingleVaraibleDifferentiationPanel  matlab.ui.container.Panel
        FunctioninxTextAreaLabel        matlab.ui.control.Label
        FunctioninxTextArea             matlab.ui.control.TextArea
        TextArea4                       matlab.ui.control.TextArea
        CalculateFirstDerivativeButton  matlab.ui.control.Button
        ValueTextAreaLabel              matlab.ui.control.Label
        ValueTextArea                   matlab.ui.control.TextArea
        DerivativeataparticularvalueofxButton  matlab.ui.control.Button
        TextArea5                       matlab.ui.control.TextArea
        CalculateSecondDerivativeButton  matlab.ui.control.Button
        TextArea6                       matlab.ui.control.TextArea
        TwoVaraibleDifferentiationPanel  matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        DifferentiatewrtTextAreaLabel   matlab.ui.control.Label
        DifferentiatewrtTextArea        matlab.ui.control.TextArea
        CalculateFirstOrderDerivativeButton  matlab.ui.control.Button
        FirstOrderDerivativeTextAreaLabel  matlab.ui.control.Label
        FirstOrderDerivativeTextArea    matlab.ui.control.TextArea
        CalculateSecondOrderDerivativeButton  matlab.ui.control.Button
        SecondOrderDerivativeLabel      matlab.ui.control.Label
        SecondOrderDerivativeTextArea   matlab.ui.control.TextArea
        FunctioninsandtLabel            matlab.ui.control.Label
        MultiVaraibleDifferentiationPanel  matlab.ui.container.Panel
        EnteraMultiVariableFunctionTextArea  matlab.ui.control.TextArea
        DerivateButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        WhenthevariablesarenotdefinedthevariableLabel  matlab.ui.control.Label
        alphabeticallyclosesttoxisselectedandtheLabel  matlab.ui.control.Label
        functionisdifferentiatedwrtthatvariableLabel  matlab.ui.control.Label
        EnteraMultiVariableLabel        matlab.ui.control.Label
        FunctionLabel                   matlab.ui.control.Label
        Topic3Tab                       matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        DisplayButton                   matlab.ui.control.Button
        FsSliderLabel                   matlab.ui.control.Label
        FsSlider                        matlab.ui.control.Slider
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            load openloop60hertz.mat;
            openLoop = openLoopVoltage;
            Fs = app.FsSlider.Value;
            t = (0:length(openLoop)-1)/Fs;
            plot(app.UIAxes2, t, openLoop);
            
            d = designfilt('bandstopiir','FilterOrder',2, ...
               'HalfPowerFrequency1',59,'HalfPowerFrequency2',61, ...
               'DesignMethod','butter','SampleRate',Fs);

            buttLoop = filtfilt(d,openLoop);
            plot(app.UIAxes, t,openLoop,t,buttLoop);
            legend(app.UIAxes3,'Unfiltered','Filtered')
            legend(app.UIAxes,'Unfiltered','Filtered')
            [popen,fopen] = periodogram(openLoop,[],[],Fs);
            [pbutt,fbutt] = periodogram(buttLoop,[],[],Fs);
            plot(app.UIAxes3, fopen,20*log10(abs(popen)),fbutt,20*log10(abs(pbutt)),'--');
            

        end

        % Button pushed function: 
        % CalculateFirstOrderDerivativeButton
        function CalculateFirstOrderDerivativeButtonPushed(app, event)
            syms s t
            a = app.TextArea.Value;
            a = str2sym(a);
            var = app.DifferentiatewrtTextArea.Value;
            b = diff(a,var);
            b = string(b);
            app.FirstOrderDerivativeTextArea.Value = b;
        end

        % Button pushed function: 
        % CalculateSecondOrderDerivativeButton
        function CalculateSecondOrderDerivativeButtonPushed(app, event)
            syms s t
            a = app.TextArea.Value;
            a = str2sym(a);
            var = app.DifferentiatewrtTextArea.Value;
            c = diff(a,var,2);
            c = string(c);
            app.SecondOrderDerivativeTextArea.Value = c;
        end

        % Button pushed function: DerivateButton
        function DerivateButtonPushed(app, event)

            b1 = app.EnteraMultiVariableFunctionTextArea.Value;
            
            b1= str2sym(b1);
            %f = sin(a*t + b1);
            h1=diff(b1);
            
            x = string(h1);
            app.Label.Text = x;
        end

        % Button pushed function: CalculateFirstDerivativeButton
        function CalculateFirstDerivativeButtonPushed(app, event)
            syms x;
            eqn = app.FunctioninxTextArea.Value;
            a = str2sym(eqn);
            b = diff(a);
            c = string(b);
            app.TextArea4.Value = c;
        end

        % Button pushed function: 
        % DerivativeataparticularvalueofxButton
        function DerivativeataparticularvalueofxButtonPushed(app, event)
            Value = app.ValueTextArea.Value;
            syms x;
            eqn = app.FunctioninxTextArea.Value;
            a = str2sym(eqn);
            b = diff(a);
            z=vpa(subs(b,x,Value));
            c = string(z);
            app.TextArea5.Value = c;
        end

        % Button pushed function: CalculateSecondDerivativeButton
        function CalculateSecondDerivativeButtonPushed(app, event)
            syms x;
            eqn = app.FunctioninxTextArea.Value;
            a = str2sym(eqn);
            b = diff(a,2);
            c = string(b);
            app.TextArea6.Value = c;
        end

        % Button pushed function: FilterAudioFileButton
        function FilterAudioFileButtonPushed(app, event)
            lpfilt = designfilt('lowpassfir','PassbandFrequency',0.05,...
                'stopbandfrequency',0.06,'PassbandRipple',0.01,...
                'StopbandAttenuation',120,'DesignMethod','kaiserwin');
            %fvtool(lpfilt);
            
            
            [file,path] = uigetfile('*.wav');
            app.Label_2.Text = file;
            app.Label_3.Text = path;
            [y,Fs1] = audioread(file); 
           

            totalTime = length(y)./Fs1;
            t = (0: totalTime/(length(y)): totalTime-(totalTime/length(y)));
            plot(app.UIAxes4,t, y);
            dataIn = y;
            dataOut = filter(lpfilt,dataIn);
            audiowrite('filteredAudio1.wav',dataOut, Fs1);
            nfft = fft(y);
            plot(app.UIAxes5,t, dataOut);
        end

        % Button pushed function: playfilteredButton
        function playfilteredButtonPushed(app, event)
         [y,Fs] = audioread('filteredAudio1.wav'); 
            player = audioplayer(y, Fs);
while 1
  playblocking(player);
stop(player);
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Topic1Tab = uitab(app.TabGroup);
            app.Topic1Tab.Title = 'Topic1';
            app.Image = uiimage(app.Topic1Tab);
            app.Image.ImageSource = 'q.jpg';
            app.FilterAudioFileButton = uibutton(app.Topic1Tab, 'push');
            app.FilterAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @FilterAudioFileButtonPushed, true);
            app.FilterAudioFileButton.Icon = 'img_488154.svg';
            app.FilterAudioFileButton.Tooltip = {'select audio file'};
            app.FilterAudioFileButton.Text = 'Filter Audio File';
            app.Label_2 = uilabel(app.Topic1Tab);
            app.Label_2.Text = ':';
            app.Label_3 = uilabel(app.Topic1Tab);
            app.Label_3.Text = ':';
            app.playfilteredButton = uibutton(app.Topic1Tab, 'push');
            app.playfilteredButton.ButtonPushedFcn = createCallbackFcn(app, @playfilteredButtonPushed, true);
            app.playfilteredButton.Icon = 'sound-bars.svg';
            app.playfilteredButton.Tooltip = {'play audio file'};
            app.playfilteredButton.Text = 'play filtered';
            app.UIAxes4 = uiaxes(app.Topic1Tab);
            title(app.UIAxes4, 'Unfiltered Audio')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.SubtitleFontWeight = 'bold';
            app.UIAxes5 = uiaxes(app.Topic1Tab);
            title(app.UIAxes5, 'Filtered Audio')
            xlabel(app.UIAxes5, 'Time')
            ylabel(app.UIAxes5, 'Amplitude')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.SubtitleFontWeight = 'bold';
            app.Topic2Tab = uitab(app.TabGroup);
            app.Topic2Tab.Title = 'Topic2';
            app.Image3 = uiimage(app.Topic2Tab);
            app.Image3.ImageSource = 'q.jpg';
            app.SingleVaraibleDifferentiationPanel = uipanel(app.Topic2Tab);
            app.SingleVaraibleDifferentiationPanel.Title = 'Single Varaible Differentiation';
            app.FunctioninxTextAreaLabel = uilabel(app.SingleVaraibleDifferentiationPanel);
            app.FunctioninxTextAreaLabel.Text = 'Function in x';
            app.FunctioninxTextArea = uitextarea(app.SingleVaraibleDifferentiationPanel);
            app.TextArea4 = uitextarea(app.SingleVaraibleDifferentiationPanel);
            app.CalculateFirstDerivativeButton = uibutton(app.SingleVaraibleDifferentiationPanel, 'push');
            app.CalculateFirstDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFirstDerivativeButtonPushed, true);
            app.CalculateFirstDerivativeButton.Icon = 'img_488154.svg';
            app.CalculateFirstDerivativeButton.Text = 'Calculate First Derivative';
            app.ValueTextAreaLabel = uilabel(app.SingleVaraibleDifferentiationPanel);
            app.ValueTextAreaLabel.Text = 'Value';
            app.ValueTextArea = uitextarea(app.SingleVaraibleDifferentiationPanel);
            app.DerivativeataparticularvalueofxButton = uibutton(app.SingleVaraibleDifferentiationPanel, 'push');
            app.DerivativeataparticularvalueofxButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeataparticularvalueofxButtonPushed, true);
            app.DerivativeataparticularvalueofxButton.Icon = 'img_488154.svg';
            app.DerivativeataparticularvalueofxButton.Text = 'Derivative at a particular value of x';
            app.TextArea5 = uitextarea(app.SingleVaraibleDifferentiationPanel);
            app.CalculateSecondDerivativeButton = uibutton(app.SingleVaraibleDifferentiationPanel, 'push');
            app.CalculateSecondDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSecondDerivativeButtonPushed, true);
            app.CalculateSecondDerivativeButton.Icon = 'img_488154.svg';
            app.CalculateSecondDerivativeButton.Text = 'Calculate Second Derivative';
            app.TextArea6 = uitextarea(app.SingleVaraibleDifferentiationPanel);
            app.TwoVaraibleDifferentiationPanel = uipanel(app.Topic2Tab);
            app.TwoVaraibleDifferentiationPanel.Title = 'Two Varaible Differentiation';
            app.TextArea = uitextarea(app.TwoVaraibleDifferentiationPanel);
            app.DifferentiatewrtTextAreaLabel = uilabel(app.TwoVaraibleDifferentiationPanel);
            app.DifferentiatewrtTextAreaLabel.Text = 'Differentiate wrt ...';
            app.DifferentiatewrtTextArea = uitextarea(app.TwoVaraibleDifferentiationPanel);
            app.CalculateFirstOrderDerivativeButton = uibutton(app.TwoVaraibleDifferentiationPanel, 'push');
            app.CalculateFirstOrderDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFirstOrderDerivativeButtonPushed, true);
            app.CalculateFirstOrderDerivativeButton.Icon = 'img_488154.svg';
            app.CalculateFirstOrderDerivativeButton.Text = 'Calculate First Order Derivative';
            app.FirstOrderDerivativeTextAreaLabel = uilabel(app.TwoVaraibleDifferentiationPanel);
            app.FirstOrderDerivativeTextAreaLabel.Text = 'First Order Derivative = ';
            app.FirstOrderDerivativeTextArea = uitextarea(app.TwoVaraibleDifferentiationPanel);
            app.CalculateSecondOrderDerivativeButton = uibutton(app.TwoVaraibleDifferentiationPanel, 'push');
            app.CalculateSecondOrderDerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSecondOrderDerivativeButtonPushed, true);
            app.CalculateSecondOrderDerivativeButton.Icon = 'img_488154.svg';
            app.CalculateSecondOrderDerivativeButton.Text = 'Calculate Second Order Derivative';
            app.SecondOrderDerivativeLabel = uilabel(app.TwoVaraibleDifferentiationPanel);
            app.SecondOrderDerivativeLabel.Text = 'Second Order Derivative =';
            app.SecondOrderDerivativeTextArea = uitextarea(app.TwoVaraibleDifferentiationPanel);
            app.FunctioninsandtLabel = uilabel(app.TwoVaraibleDifferentiationPanel);
            app.FunctioninsandtLabel.Text = 'Function in ''s'' and ''t''';
            app.MultiVaraibleDifferentiationPanel = uipanel(app.Topic2Tab);
            app.MultiVaraibleDifferentiationPanel.Title = 'Multi - Varaible Differentiation';
            app.EnteraMultiVariableFunctionTextArea = uitextarea(app.MultiVaraibleDifferentiationPanel);
            app.DerivateButton = uibutton(app.MultiVaraibleDifferentiationPanel, 'push');
            app.DerivateButton.ButtonPushedFcn = createCallbackFcn(app, @DerivateButtonPushed, true);
            app.DerivateButton.Icon = 'img_488154.svg';
            app.DerivateButton.Text = 'Derivate';
            app.Label = uilabel(app.MultiVaraibleDifferentiationPanel);
            app.Label.Text = '.';
            app.WhenthevariablesarenotdefinedthevariableLabel = uilabel(app.MultiVaraibleDifferentiationPanel);
            app.WhenthevariablesarenotdefinedthevariableLabel.Text = 'When the variables are not defined, the variable';
            app.alphabeticallyclosesttoxisselectedandtheLabel = uilabel(app.MultiVaraibleDifferentiationPanel);
            app.alphabeticallyclosesttoxisselectedandtheLabel.Text = 'alphabetically closest to x is selected and the';
            app.functionisdifferentiatedwrtthatvariableLabel = uilabel(app.MultiVaraibleDifferentiationPanel);
            app.functionisdifferentiatedwrtthatvariableLabel.Text = 'function is differentiated wrt that variable.';
            app.EnteraMultiVariableLabel = uilabel(app.MultiVaraibleDifferentiationPanel);
            app.EnteraMultiVariableLabel.Text = 'Enter a Multi-Variable';
            app.FunctionLabel = uilabel(app.MultiVaraibleDifferentiationPanel);
            app.FunctionLabel.Text = 'Function';
            app.Topic3Tab = uitab(app.TabGroup);
            app.Topic3Tab.Title = 'Topic3';
            app.Image2 = uiimage(app.Topic3Tab);
            app.Image2.ImageSource = 'q.jpg';
            app.DisplayButton = uibutton(app.Topic3Tab, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Icon = 'statistics.svg';
            app.DisplayButton.Text = 'Display';
            app.FsSliderLabel = uilabel(app.Topic3Tab);
            app.FsSliderLabel.Text = 'Fs';
            app.FsSlider = uislider(app.Topic3Tab);
            app.FsSlider.Limits = [500 4000];
            app.FsSlider.Tooltip = {'select fs'};
            app.FsSlider.Value = 500;
            app.UIAxes2 = uiaxes(app.Topic3Tab);
            title(app.UIAxes2, 'Open-Loop Voltage with 60 Hz Noise')
            xlabel(app.UIAxes2, 'Time(s)')
            ylabel(app.UIAxes2, 'Voltage(V)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.Topic3Tab);
            title(app.UIAxes, 'Open-Loop Voltage')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Voltage(V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.Topic3Tab);
            title(app.UIAxes3, 'Power Spectrum')
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Power/frequency (dB/Hz)')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end